classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        slectionnervotreimageButton     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        InverserlimageButton            matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        couperunepartiedelimageButton   matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        TournerlimageButton             matlab.ui.control.Button
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        obtenirlhistogrammeButton       matlab.ui.control.Button
        fairelescanningButton           matlab.ui.control.Button
        FentresuivanteButton            matlab.ui.control.Button
        obtenirleprofildintensitselonlaligneButton  matlab.ui.control.Button
        obtenirleprofildintensitselonlacolonneButton  matlab.ui.control.Button
        CalculerlatailledelimageButton  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        XLabel                          matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        DonneruneangleEditFieldLabel    matlab.ui.control.Label
        DonneruneangleEditField         matlab.ui.control.EditField
        donnerlaligneEditFieldLabel     matlab.ui.control.Label
        donnerlaligneEditField          matlab.ui.control.EditField
        donnerlacolonneEditFieldLabel   matlab.ui.control.Label
        donnerlacolonneEditField        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: slectionnervotreimageButton
        function slectionnervotreimageButtonPushed(app, event)
            global im;
        [rawname, rawpath] = uigetfile({'*.BMP*';'*.jpg*';'*.png*'},'Select Image Data');
        fullname    = [rawpath rawname];
        
        im=imread(fullname);
       imshow(im, 'Parent', app.UIAxes);
        end

        % Button pushed function: InverserlimageButton
        function InverserlimageButtonPushed(app, event)
            global im;
  
         imshow(255-im,'Parent', app.UIAxes2);
        end

        % Button pushed function: couperunepartiedelimageButton
        function couperunepartiedelimageButtonPushed(app, event)
             global im;
            pA=im(1:150, 1:120);
            imshow(pA ,'Parent', app.UIAxes3)
        end

        % Button pushed function: TournerlimageButton
        function TournerlimageButtonPushed(app, event)
    
             global im angle ;
           rA=imrotate(im,angle);
           imshow(rA,'Parent',app.UIAxes4);
        end

        % Button pushed function: CalculerlatailledelimageButton
        function CalculerlatailledelimageButtonPushed(app, event)
            global im rows columns;
            [rows,columns]= size(im);
       app.EditField.Value = string(rows) ;
       app.EditField_2.Value = string(columns) ;
        end

        % Value changed function: DonneruneangleEditField
        function DonneruneangleEditFieldValueChanged(app, event)
            value = app.DonneruneangleEditField.Value;
            global angle ;
           angle = str2double(value) ;
        end

        % Button pushed function: obtenirlhistogrammeButton
        function obtenirlhistogrammeButtonPushed(app, event)
            global im;
            hA = imhist(im);
            plot(hA,'Parent',app.UIAxes5);
        end

        % Button pushed function: 
        % obtenirleprofildintensitselonlaligneButton
        function obtenirleprofildintensitselonlaligneButtonPushed(app, event)
            global im ligne ;
            improfile=im(ligne,:);
           plot(improfile,'Parent',app.UIAxes6);
        end

        % Value changed function: donnerlaligneEditField
        function donnerlaligneEditFieldValueChanged(app, event)
            value = app.donnerlaligneEditField.Value;
            global ligne ;
           ligne = str2double(value) ;
        end

        % Value changed function: donnerlacolonneEditField
        function donnerlacolonneEditFieldValueChanged(app, event)
            value = app.donnerlacolonneEditField.Value;
             global colonne;
           colonne= str2double(value) ;
        end

        % Button pushed function: 
        % obtenirleprofildintensitselonlacolonneButton
        function obtenirleprofildintensitselonlacolonneButtonPushed(app, event)
            global im colonne ;
            improfile=im(:,colonne);
           plot(improfile,'Parent',app.UIAxes7); 
        end

        % Button pushed function: fairelescanningButton
        function fairelescanningButtonPushed(app, event)
            global im ;
            e=zeros(256,256);
         for i=1: 256 
            for j=1 : 256
        
               if im(i,j)==125
                  e(i,j)=255;
         
               end   
            end 
        end 

        ng=im(150:150, :);
        imshow(ng,'Parent',app.UIAxes8);
        imshow(e,'Parent',app.UIAxes8)  ;
        end

        % Button pushed function: FentresuivanteButton
        function FentresuivanteButtonPushed(app, event)
            delete(app)
         run("filtre.mlapp")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.slectionnervotreimageButton = uibutton(app.UIFigure, 'push');
            app.slectionnervotreimageButton.ButtonPushedFcn = createCallbackFcn(app, @slectionnervotreimageButtonPushed, true);
            app.slectionnervotreimageButton.Text = 'sélectionner votre image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image originale')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Inversion de couleur')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.InverserlimageButton = uibutton(app.UIFigure, 'push');
            app.InverserlimageButton.ButtonPushedFcn = createCallbackFcn(app, @InverserlimageButtonPushed, true);
            app.InverserlimageButton.Text = 'Inverser l''image';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'une partie de l''image')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.couperunepartiedelimageButton = uibutton(app.UIFigure, 'push');
            app.couperunepartiedelimageButton.ButtonPushedFcn = createCallbackFcn(app, @couperunepartiedelimageButtonPushed, true);
            app.couperunepartiedelimageButton.Text = 'couper une partie de l''image';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Image tournée ')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.TournerlimageButton = uibutton(app.UIFigure, 'push');
            app.TournerlimageButton.ButtonPushedFcn = createCallbackFcn(app, @TournerlimageButtonPushed, true);
            app.TournerlimageButton.Text = 'Tourner l''image';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Histogramme')
            xlabel(app.UIAxes5, 'Nbre de pixels')
            ylabel(app.UIAxes5, 'Nbre d apparition')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Profil d''intensité selon ligne')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Profil d''intensité selon colonne')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Scanning de la pixel')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.obtenirlhistogrammeButton = uibutton(app.UIFigure, 'push');
            app.obtenirlhistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @obtenirlhistogrammeButtonPushed, true);
            app.obtenirlhistogrammeButton.Text = 'obtenir l''histogramme';
            app.fairelescanningButton = uibutton(app.UIFigure, 'push');
            app.fairelescanningButton.ButtonPushedFcn = createCallbackFcn(app, @fairelescanningButtonPushed, true);
            app.fairelescanningButton.Text = 'faire le scanning';
            app.FentresuivanteButton = uibutton(app.UIFigure, 'push');
            app.FentresuivanteButton.ButtonPushedFcn = createCallbackFcn(app, @FentresuivanteButtonPushed, true);
            app.FentresuivanteButton.Text = 'Fenêtre suivante';
            app.obtenirleprofildintensitselonlaligneButton = uibutton(app.UIFigure, 'push');
            app.obtenirleprofildintensitselonlaligneButton.ButtonPushedFcn = createCallbackFcn(app, @obtenirleprofildintensitselonlaligneButtonPushed, true);
            app.obtenirleprofildintensitselonlaligneButton.Text = 'obtenir le profil d''intensité selon la ligne';
            app.obtenirleprofildintensitselonlacolonneButton = uibutton(app.UIFigure, 'push');
            app.obtenirleprofildintensitselonlacolonneButton.ButtonPushedFcn = createCallbackFcn(app, @obtenirleprofildintensitselonlacolonneButtonPushed, true);
            app.obtenirleprofildintensitselonlacolonneButton.Text = 'obtenir le profil d''intensité selon la colonne';
            app.CalculerlatailledelimageButton = uibutton(app.UIFigure, 'push');
            app.CalculerlatailledelimageButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerlatailledelimageButtonPushed, true);
            app.CalculerlatailledelimageButton.Text = 'Calculer la taille de l''image';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.DonneruneangleEditFieldLabel = uilabel(app.UIFigure);
            app.DonneruneangleEditFieldLabel.Text = 'Donner une angle';
            app.DonneruneangleEditField = uieditfield(app.UIFigure, 'text');
            app.DonneruneangleEditField.ValueChangedFcn = createCallbackFcn(app, @DonneruneangleEditFieldValueChanged, true);
            app.DonneruneangleEditField.Tooltip = {'Donner une valeur entre 0° et 360°'};
            app.donnerlaligneEditFieldLabel = uilabel(app.UIFigure);
            app.donnerlaligneEditFieldLabel.Text = 'donner la ligne';
            app.donnerlaligneEditField = uieditfield(app.UIFigure, 'text');
            app.donnerlaligneEditField.ValueChangedFcn = createCallbackFcn(app, @donnerlaligneEditFieldValueChanged, true);
            app.donnerlaligneEditField.Tooltip = {'must not exceed 256'};
            app.donnerlacolonneEditFieldLabel = uilabel(app.UIFigure);
            app.donnerlacolonneEditFieldLabel.Text = 'donner la colonne';
            app.donnerlacolonneEditField = uieditfield(app.UIFigure, 'text');
            app.donnerlacolonneEditField.ValueChangedFcn = createCallbackFcn(app, @donnerlacolonneEditFieldValueChanged, true);
            app.donnerlacolonneEditField.Tooltip = {'must not exceed 256'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end