classdef filtre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        slectionnervotreimageButton   matlab.ui.control.Button
        PrcedenteButton               matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        AppliquerlebruitButton        matlab.ui.control.Button
        AplliquerlefiltreButton       matlab.ui.control.Button
        ChoisirunbruitDropDownLabel   matlab.ui.control.Label
        ChoisirunbruitDropDown        matlab.ui.control.DropDown
        ChoisirunfiltreDropDownLabel  matlab.ui.control.Label
        ChoisirunfiltreDropDown       matlab.ui.control.DropDown
        lavaleurdePeakSNREditFieldLabel  matlab.ui.control.Label
        lavaleurdePeakSNREditField    matlab.ui.control.EditField
        lavaleurSNREditFieldLabel     matlab.ui.control.Label
        lavaleurSNREditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: slectionnervotreimageButton
        function slectionnervotreimageButtonPushed(app, event)
            global im;
            [rawname, rawpath] = uigetfile({'*.BMP*';'*.jpg*';'*.png*'},'Select Image Data');
            fullname    = [rawpath rawname];
            
            im=imread(fullname);
            imshow(im, 'Parent', app.UIAxes);
        end

        % Button pushed function: PrcedenteButton
        function PrcedenteButtonPushed(app, event)
            delete(app)
            run("Interface.mlapp")
        end

        % Value changed function: ChoisirunbruitDropDown
        function ChoisirunbruitDropDownValueChanged(app, event)
            value = app.ChoisirunbruitDropDown.Value;
            global f1 ;
            
            f1= string(value);
        end

        % Button pushed function: AppliquerlebruitButton
        function AppliquerlebruitButtonPushed(app, event)
            global im J f1 ;
            
            J=im ;
            
             if f1=="salt & pepper"
                J=imnoise(J,'salt & pepper');
                imshow(J,'Parent',app.UIAxes2);
             else
                J=imnoise(J,'speckle');
                imshow(J,'Parent',app.UIAxes2);
            end
        end

        % Value changed function: ChoisirunfiltreDropDown
        function ChoisirunfiltreDropDownValueChanged(app, event)
            value = app.ChoisirunfiltreDropDown.Value;
            global f2 ;
            
            f2= string(value) ;
        end

        % Button pushed function: AplliquerlefiltreButton
        function AplliquerlefiltreButtonPushed(app, event)
            global im J f2 peaksnr snr ;
            
            
            if f2=="Filtre moyenneur"
                
                
                h=fspecial('average',[3 3]);
                Y0=imfilter(J,h,'replicate');
                [peaksnr, snr] = psnr(J, im);
                app.lavaleurdePeakSNREditField.Value =  string(peaksnr) ;
                app.lavaleurSNREditField.Value = string(snr);
                imshow(Y0,'Parent',app.UIAxes3);
                
            else
                
                Y1=medfilt2(J,[3 3]);
                [peaksnr, snr] = psnr(J, im);
                app.lavaleurdePeakSNREditField.Value = string(peaksnr) ;
                app.lavaleurSNREditField.Value = string(snr) ;
                imshow(Y1,'Parent',app.UIAxes3);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'image séléctionnée')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.slectionnervotreimageButton = uibutton(app.UIFigure, 'push');
            app.slectionnervotreimageButton.ButtonPushedFcn = createCallbackFcn(app, @slectionnervotreimageButtonPushed, true);
            app.slectionnervotreimageButton.Text = 'sélectionner votre image';
            app.PrcedenteButton = uibutton(app.UIFigure, 'push');
            app.PrcedenteButton.ButtonPushedFcn = createCallbackFcn(app, @PrcedenteButtonPushed, true);
            app.PrcedenteButton.Text = 'Précedente';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'image bruitée')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'image filtrée')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.AppliquerlebruitButton = uibutton(app.UIFigure, 'push');
            app.AppliquerlebruitButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerlebruitButtonPushed, true);
            app.AppliquerlebruitButton.Text = 'Appliquer le bruit';
            app.AplliquerlefiltreButton = uibutton(app.UIFigure, 'push');
            app.AplliquerlefiltreButton.ButtonPushedFcn = createCallbackFcn(app, @AplliquerlefiltreButtonPushed, true);
            app.AplliquerlefiltreButton.Text = 'Aplliquer le filtre';
            app.ChoisirunbruitDropDownLabel = uilabel(app.UIFigure);
            app.ChoisirunbruitDropDownLabel.Text = 'Choisir un bruit';
            app.ChoisirunbruitDropDown = uidropdown(app.UIFigure);
            app.ChoisirunbruitDropDown.Items = {'', 'speckle', 'salt & pepper'};
            app.ChoisirunbruitDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoisirunbruitDropDownValueChanged, true);
            app.ChoisirunbruitDropDown.Tooltip = {'sélectionner soit speckle soit salt and pepper'};
            app.ChoisirunbruitDropDown.Value = '';
            app.ChoisirunfiltreDropDownLabel = uilabel(app.UIFigure);
            app.ChoisirunfiltreDropDownLabel.Text = 'Choisir un filtre';
            app.ChoisirunfiltreDropDown = uidropdown(app.UIFigure);
            app.ChoisirunfiltreDropDown.Items = {'', 'Filtre moyenneur', 'Filtrage median  3 x 3'};
            app.ChoisirunfiltreDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoisirunfiltreDropDownValueChanged, true);
            app.ChoisirunfiltreDropDown.Tooltip = {'sélectionner soit Filtre moyenneur'; 'soit Filtrage median  3 x 3 '};
            app.ChoisirunfiltreDropDown.Value = '';
            app.lavaleurdePeakSNREditFieldLabel = uilabel(app.UIFigure);
            app.lavaleurdePeakSNREditFieldLabel.Text = 'la valeur de Peak-SNR';
            app.lavaleurdePeakSNREditField = uieditfield(app.UIFigure, 'text');
            app.lavaleurSNREditFieldLabel = uilabel(app.UIFigure);
            app.lavaleurSNREditFieldLabel.Text = 'la valeur SNR';
            app.lavaleurSNREditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filtre

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end